classdef DualSliderWidgetStagingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure      matlab.ui.Figure
        MainLayout      matlab.ui.container.GridLayout
        DualSlider      wt.DualSlider
        SliderSpinner   wt.SliderSpinner
        MWSliderLayout  matlab.ui.container.GridLayout
        Slider          matlab.ui.control.Slider
        DisplayAxis     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

        % - MW Slider Plot Properties
        SliderPlot (1,1) = gobjects(1,1);
        SliderX (:,1) = 1:100;
        SliderY (:,1) = 50 + 10 * rand([100,1]);

        % - Dual Slider Plot Handle
        DualSliderPlots (2,1) = gobjects(2,1);
        DualSliderValues (2,1) double = [50;50];

    end


    methods (Access = private)
        
        function gUpdate(app)
            % GUPDATE - Updates the graphics objects in the app.
            %  Call this to update any images drawn to the main axis.
    
            % Reset Dual Slider Plot Values
            for i = 1:2
                set(app.DualSliderPlots(i),...
                    "Value",app.DualSliderValues(i));
            end 

            % Masek Color
            maskColor = zeros(100,3);

            for i = 1:100
                if (i > app.DualSliderValues(1) && i < app.DualSliderValues(2))
                    maskColor(i,:) = [0,1,0];
                else
                    maskColor(i,:) = [1,0,0];
                end
            end

            set(app.SliderPlot,"CData",maskColor);

            % Force Graphics Update
            drawnow;

        end %function

    end %methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % -- Center FIgure
            movegui(app.MainFigure,"center");
            
            % - Initialize Plot Parameters
            % Slider Point Plot
            app.SliderPlot = scatter(app.DisplayAxis,...
                app.SliderX, app.SliderY, 10, "red");
            

            % Dual-Slider Boundary Plot
            for i = 1:2
                % Update Both Dual Slider Plots
                app.DualSliderPlots(i) = xline(app.DisplayAxis,...
                    app.DualSliderValues(i),...
                    "Color","k");
            end
            
            % Run GUpdate
            app.gUpdate();

            % Output App to workspace for debugging.
            assignin("base","app",app);           
            
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            % SliderValueChanging: Triggers when slider value is changing.
            %  Use this method to live-update the axis point positions
            % Store new Value into Property

            % Run Graphics Update
            app.gUpdate();
            
        end

        % Callback function: DualSlider
        function DualSliderValueChanging(app, event)
            % Triggers on Value Changed (Slider Thumb)
            disp("Slider value is changing");

            % Get Changing Value
            app.DualSliderValues = app.DualSlider.Value;

            % Update Graphics
            app.gUpdate();
            
        end

        % Callback function: SliderSpinner
        function SliderSpinnerValueChanged(app, event)
            value = app.SliderSpinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'MATLAB App';
            app.MainLayout = uigridlayout(app.MainFigure);
            app.DisplayAxis = uiaxes(app.MainLayout);
            title(app.DisplayAxis, 'Slider Control Test Axis')
            xlabel(app.DisplayAxis, 'X')
            ylabel(app.DisplayAxis, 'Y')
            zlabel(app.DisplayAxis, 'Z')
            app.DisplayAxis.XLim = [0 100];
            app.DisplayAxis.YLim = [0 100];
            app.MWSliderLayout = uigridlayout(app.MainLayout);
            app.Slider = uislider(app.MWSliderLayout);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 50;
            app.SliderSpinner = wt.SliderSpinner(app.MainLayout);
            app.SliderSpinner.MinorTickLabels = [0 0.8 1.6 2.4 3.2 4 4.8 5.6 6.4 7.2 8 8.8 9.6 10.4 11.2 12 12.8 13.6 14.4 15.2 16 16.8 17.6 18.4 19.2 20 20.8 21.6 22.4 23.2 24 24.8 25.6 26.4 27.2 28 28.8 29.6 30.4 31.2 32 32.8 33.6 34.4 35.2 36 36.8 37.6 38.4 39.2 40 40.8 41.6 42.4 43.2 44 44.8 45.6 46.4 47.2 48 48.8 49.6 50.4 51.2 52 52.8 53.6 54.4 55.2 56 56.8 57.6 58.4 59.2 60 60.8 61.6 62.4 63.2 64 64.8 65.6 66.4 67.2 68 68.8 69.6 70.4 71.2 72 72.8 73.6 74.4 75.2 76 76.8 77.6 78.4 79.2 80 80.8 81.6 82.4 83.2 84 84.8 85.6 86.4 87.2 88 88.8 89.6 90.4 91.2 92 92.8 93.6 94.4 95.2 96 96.8 97.6 98.4 99.2 100];
            app.SliderSpinner.ValueChangedFcn = createCallbackFcn(app, @SliderSpinnerValueChanged, true);
            app.DualSlider = wt.DualSlider(app.MainLayout);
            app.DualSlider.Value = [5 50];
            app.DualSlider.ValueChangingFcn = createCallbackFcn(app, @DualSliderValueChanging, true);
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DualSliderWidgetStagingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end